classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        ExtremwertefralleKonfigurationenLabel  matlab.ui.control.Label
        KonfigurationDropDown          matlab.ui.control.DropDown
        KonfigurationDropDownLabel     matlab.ui.control.Label
        AbweichungLabel                matlab.ui.control.Label
        GeschwindigkeitLabel           matlab.ui.control.Label
        d_FallschirmEditField          matlab.ui.control.NumericEditField
        FallschirmdurchmcmLabel        matlab.ui.control.Label
        d_FallschirmLabel              matlab.ui.control.Label
        FlughoeheLabel                 matlab.ui.control.Label
        MasseLabel                     matlab.ui.control.Label
        StabilitaetLabel               matlab.ui.control.Label
        x_CGLabel                      matlab.ui.control.Label
        DruckpunktLabel                matlab.ui.control.Label
        HinterkantenpfeilungSlider     matlab.ui.control.Slider
        VorderkantenpfeilungSlider     matlab.ui.control.Slider
        FlaechenverhaeltnisSlider      matlab.ui.control.Slider
        RumpflaengeSlider              matlab.ui.control.Slider
        NasenlaengeSlider              matlab.ui.control.Slider
        StreckungSlider                matlab.ui.control.Slider
        FlossenzahlSpinner             matlab.ui.control.Spinner
        FlossenzahlSpinnerLabel        matlab.ui.control.Label
        RumpfdurchmesserDropDown       matlab.ui.control.DropDown
        l_aLabel                       matlab.ui.control.Label
        l_iLabel                       matlab.ui.control.Label
        SpannweiteLabel                matlab.ui.control.Label
        A_MantelLabel                  matlab.ui.control.Label
        A_LatLabel                     matlab.ui.control.Label
        A_FlosseLabel                  matlab.ui.control.Label
        wLabel                         matlab.ui.control.Label
        c_w_gesLabel                   matlab.ui.control.Label
        phi50Label                     matlab.ui.control.Label
        StreckungEditField             matlab.ui.control.NumericEditField
        StreckungEditFieldLabel        matlab.ui.control.Label
        HinterkantenpfeilungEditField  matlab.ui.control.NumericEditField
        HinterkantenpfeilungLabel      matlab.ui.control.Label
        VorderkantenpfeilungEditField  matlab.ui.control.NumericEditField
        VorderkantenpfeilungLabel      matlab.ui.control.Label
        RumpfdurchmessermmEditFieldLabel  matlab.ui.control.Label
        FlaechenverhaeltnisEditField   matlab.ui.control.NumericEditField
        FlchenverhltnisEditFieldLabel  matlab.ui.control.Label
        NasenformDropDown              matlab.ui.control.DropDown
        NasenformDropDownLabel         matlab.ui.control.Label
        RumpflaengeEditField           matlab.ui.control.NumericEditField
        RumpflngecmLabel               matlab.ui.control.Label
        NasenlaengeEditField           matlab.ui.control.NumericEditField
        NasenlngecmLabel               matlab.ui.control.Label
        FluggeschwindigkeitAxes        matlab.ui.control.UIAxes
        contourAxes                    matlab.ui.control.UIAxes
        FlugbahnAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        d_flosse = 0.003; % 3 mm
        u_mittel = 30; % 30m/s
        mu0 = 17.17e-6; % Dynamische Viskosität auf Meereshöhe nach ISA
        Temp0 = 288.15; % Kelvin
        rho0 = 1.225; % Luftdichte auf Meereshöhe (kg/m3) nach ISA
        v0 = 14.607e-6; % kinematische Viskositaet auf Meereshöhe (m^2/s)
        masse1=-1;    % Startmasse in kg - Konfig 1 (wird sofort mit richtigem Wert überschrieben)
        masse2=-1;    % Startmasse in kg - Konfig 2 (wird sofort mit richtigem Wert überschrieben)
        masse3=-1;    % Startmasse in kg - Konfig 3 (wird sofort mit richtigem Wert überschrieben)
        c_W_Fallschirm=0.75;  % c_W des Fallschirms bezogen auf die Fläche des flachen Schirms
    end

    properties (Dependent)
        A_ref
        d_rumpf
        l_rumpf
        l_nase
        l_rohr
        alpha_vor
        alpha_hinter
        streckung
        A_fallschirm
        d_fallschirm
        d_fallschirmMin
        konfiguration

        % nur getter, keine Unit conversion etc.
        n_flossen
    end
    
    methods % nur getter und setter
        function a = get.A_ref(app) % Referenzfläche A_ref (m^2)
            a = pi * (app.d_rumpf.^2 / 4); % mm in m, d -> r
            %app.A_refLabel.Text = app.prep_latex_str("A_\text{ref}", a * 1e4 ,"cm^2");
        end

        function a = get.d_rumpf(app) % Rumpfdurchmesser (m)
            a = app.RumpfdurchmesserDropDown.Value/1000; % mm -> m
        end

        function a = get.l_rumpf(app)
            a = app.RumpflaengeEditField.Value / 100; % cm -> m
        end

        function a = get.l_nase(app)
            a = app.NasenlaengeEditField.Value / 100; % cm -> m
        end

        function n = get.n_flossen(app)
            n = app.FlossenzahlSpinner.Value;
        end

        function l = get.l_rohr(app)
            l = app.l_rumpf - app.l_nase;
        end

        function alpha = get.alpha_vor(app)
            alpha = deg2rad(app.VorderkantenpfeilungEditField.Value);
        end

        function alpha = get.alpha_hinter(app)
            alpha = deg2rad(app.HinterkantenpfeilungEditField.Value);
        end

        function streck = get.streckung(app)
            streck = app.StreckungEditField.Value;
        end

        function d = get.d_fallschirm(app)
            d = app.d_FallschirmEditField.Value/100;
        end


        function A = get.A_fallschirm(app)
            A = pi*(app.d_fallschirm/2)^2;
        end

        function d = get.d_fallschirmMin(app)
            v_end = 4;   % Endgeschwindigkeit in m/s
            A = 2*(app.masse1-0.00758)*9.81 / (app.rho0*v_end^2*app.c_W_Fallschirm);
            d = sqrt(A/pi)*2;  % *2 nicht vergessen, um von r auf d zu kommen
        end

        function k = get.konfiguration(app)
            k = app.KonfigurationDropDown.Value;
        end

    end

    methods (Static) % static methods

        % Formattiert einen String fuer die Anzeige in einem Label mit
        % Einheiten und co.
        function s = prep_latex_str(name, value, unit, precision)
            arguments
                name string
                value (1,1) double
                unit string
                precision (1,1) int8 {mustBePositive} = 3
            end

            s = compose("$%s = %9." + string(precision) + "f\\,\\mathrm{%s}$", name, value, unit);
        end
    end

    methods (Access = private)
        function r = gesamtwiderstand(app) % (kg*m/s^2 == W/s)
            r = app.gesamtwiderstandsbeiwert() * app.rho0 * app.u_mittel.^2 * 0.5 * app.A_ref;
            app.wLabel.Text = app.prep_latex_str("\text{Gesamtwiderstand }W", r, "N", 4);
        end
        
        function r = gesamtwiderstandsbeiwert(app) % einheitslos
            r = app.c_W_rumpf_min() * app.A_mantel()/app.A_ref + app.n_flossen * app.c_W_flosse_min() * app.A_Flosse()/app.A_ref;
            app.c_w_gesLabel.Text = app.prep_latex_str("c_{W_\text{ges}}", r, "", 4);
           
        end
        
        function A = A_mantel(app) % Enthält die Mantelfläche auch den Boden am Ende? Nein!
            r_rumpf = app.d_rumpf/2; % convenience

            if app.NasenformDropDown.Value == "Parabel" % Parabolid
                % formel: https://rechneronline.de/pi/paraboloid-e.php
                A = (pi * r_rumpf)/(6 * app.l_nase^2) * ((r_rumpf^2 + 4*app.l_nase^2)^1.5 - r_rumpf^3);
            else % Ellipsoid
                % formel für die Oberfläche eines Ellipsoiden (Wikipedia)
                sqrt_c2_minus_a2 = sqrt(app.l_nase^2 - r_rumpf^2); % c ist nasenlaenge und a ist rumpfradius
                A = pi * r_rumpf * (r_rumpf + (app.l_nase^2)/sqrt_c2_minus_a2 * asin(sqrt_c2_minus_a2/app.l_nase));
            end
            A = A + app.d_rumpf * pi * app.l_rohr; % ohne Bodenfläche 
            app.A_MantelLabel.Text = app.prep_latex_str("A_\text{Mantel}", A * 1e4, "cm^2",1);
        end

        function r = c_W_flosse_min(app)
            re = app.reynolds(app.l_m());
            r = 2 * app.c_f_tu(re) * (1 + app.k_flosse() * cos(app.phi50()).^2);
            % app.c_W_FlosseMinLabel.Text = app.prep_latex_str("c_{W_\text{Flosse,min}}", r, "", 4);
        end
        
        function r = k_flosse(app) % einheitlos
            delta = app.d_flosse/app.l_m();
            r = 2.7 * delta + 100 * delta.^4;
        end
        
        function r = l_m(app) % Mittlere Flossentiefe: aus folien (m)
            r = app.A_Flosse()/app.s();
        end

        function r = s(app) % (Flossen-)Spannweite (m)
            r = sqrt(app.streckung/4 * app.A_Flosse());
            app.SpannweiteLabel.Text = app.prep_latex_str("\text{Spannweite }s", r * 1e2, "cm");
        end
        
        function r = A_Flosse(app) % (m^2)
            % ich glaube nicht an diese komische Projektion!!!
            factor = 1; % wie groß ist A_LEIT im vlg zu A_Flosse
            if app.n_flossen == 3
                factor = 3/2;
            elseif app.n_flossen == 4
                factor = 2;
            else
            end

            r = app.A_Lat() * app.FlaechenverhaeltnisEditField.Value / factor; 
            app.A_FlosseLabel.Text = app.prep_latex_str("A_\text{Flosse}", r * 1e4, "cm^2",1);
        end

        function r = A_Lat(app) % (m^2)
            r = app.d_rumpf * app.l_rohr + A_Nose(app);
            app.A_LatLabel.Text = app.prep_latex_str("A_\text{Lat}", r * 1e4, "cm^2",1);
        end
        
        function r = A_Nose(app) % complete
            % 0: parabel
            % 1: ellipse
            if app.NasenformDropDown.Value == "Parabel" 
                % Integrieren mit MatLab:
                %r_rumpf = app.d_rumpf/2;
                %fun = @(x) r_rumpf*((x/app.l_nase).^(0.5));
                %r = 2 * integral(fun, 0, app.l_nase);
                r = 2*(app.d_rumpf * app.l_nase)/3; % A(l,r)=2*(2/3)*l*r (*2 fuer l und r)
            else
                r = 0.25 * app.d_rumpf * app.l_nase * pi; % 0.5 * r * l
            end
        end
        
        function r = c_f_tu(~,re) % c_f (Turbolent), einheitslos
            r = 0.455/(log10(re).^2.58);
        end
        
        function r = phi50(app) % mittelwert aus vorder- und hinterkantenpfeilung, aber im Tangens!
            r = atan((tan(app.alpha_vor) + tan(app.alpha_hinter))/2);
            app.phi50Label.Text = app.prep_latex_str("\phi_{50}", rad2deg(r), "\!°", 2);
        end
        
        function r = reynolds(app, laenge) % einheitslos
            r = (app.u_mittel * laenge) / app.v0;
        end

        function r = k_rumpf(app) % einheitslos
            lambda_rumpf_eff = app.l_rumpf/app.d_rumpf; % Schlankheitsmaß lambda
            r = (2.2/(lambda_rumpf_eff^1.5)) + (3.8/(lambda_rumpf_eff^3));
        end

        function r = c_W_rumpf_min(app) % einheitslos
            re = app.reynolds(app.l_rumpf);
            r = app.c_f_tu(re) * (1+app.k_rumpf());
            % app.c_W_RuMinLabel.Text = app.prep_latex_str("c_{W_\text{Ru,min}}", r, "", 4);
        end

        function F = F_Schub(~,t)
            % -- F = F_Schub (t)
            %     Gib den Schub zur Zeit t zurück.


            % - gemittelter Schubverlauf -
            % Zeit in s | Schub in N

            schubdaten = [0	0.10486
                0.0125	0.10944
                0.025	0.13404
                0.0375	0.14792
                0.05	0.18148
                0.0625	0.23372
                0.075	0.3032
                0.0875	0.4239
                0.1	0.47586
                0.1125	0.52982
                0.125	0.55224
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                1.4	-0.08714];

            if t >= 0 && t <= 1.4
                [~, index] = min(abs(schubdaten(:,1)-t));
                F = schubdaten(index,2);
            else
                F = 0;
            end
        end


        function h = flugbahn(app)
            %% Startwerte definieren
            ax_h = app.FlugbahnAxes;
            cla(ax_h);
            ax_v = app.FluggeschwindigkeitAxes;
            cla(ax_v);

            % c_W der Rakete ohne Fallschirm
            c_W_rakete=app.gesamtwiderstandsbeiwert();

            % c_W des Fallschirms bezogen auf die Fläche des flachen Schirms
            c_W_fallschirm=app.c_W_Fallschirm;

            m_Treibstoff=0.00758;  % Treibstoffmasse in kg
            t_brenn = 1.4;         % Brenndauer in s
            t_stoss = 5.2;         % Zeitpunkt des Auswurfstoßes in s
            gamma=85;              % Startwinkel zum Boden in °
            
            d=app.d_rumpf;         % Rumpfdurchmesser in m
            
            rho = 1.225;           % Luftdichte in kg/m^3
            
            %%

            function [h_max, x_max, v_max] = plot_with_m(m_Start)
                % Aktuelle Bewegungsgrößen: Anfangsbedingungen
                a=0;  % (muss nicht vorgegeben werden)
                t=0;
                v=[0 0];
                s=[0 0];
                
                % Vektoren zum Abspeichern der Verläufe der Bewegungsgrößen
                t_vec=[t];
                a_vec=[0 0];
                v_vec=[v];
                s_vec=[s];
                
                while (t<1) || (s(2) > 0)
            
                  % Berechnung von aktueller Flugrichtung, Zeitintervall, cW und Masse
                  if t <= t_brenn  % Brennphase
                    richtung = [cosd(gamma) sind(gamma)];
                    delta_t = 0.0125;
                  else % Abgebrannt-Phase (?)
                    richtung = v/norm(v);
                    delta_t = 0.01;
                  end
    
                  if t <= t_stoss  % Steigphase
                    c_W = c_W_rakete;
                    A = app.A_ref;
                  else % Brems-/Sinkphase
                    c_W = c_W_fallschirm;
                    A = app.A_fallschirm;
                  end
    
                  % abschnittsweise definiertes Polynom für Masseverlauf m(t)
                  polynom = mkpp([-1 0 t_brenn t_brenn+1],[0 m_Start; (-m_Treibstoff/t_brenn) m_Start; 0 m_Start-m_Treibstoff]);
                  m = ppval(polynom, t);
                
                  % - LÖSUNG DER DGL -
                  % Berechnung der Ableitung (Beschleunigung)
                  a = richtung*app.F_Schub(t)/m + [0 -9.81] - richtung * c_W * A * rho/2 * (v(end)*v(end)') / m;
                
                  % Zeitschritt (Euler-Verfahren)
                  v = v + a * delta_t;
                  s = s + v * delta_t;
                  if s(2) < 0  % Sonderfall: Flugbahn führt in den Boden (h < 0)
                    if max(s_vec(:,2)) == 0
                      % Rakete steht noch auf Startrampe, ist noch nicht geflogen
                      s = [0 0];
                    else
                      % Rakete ist bereits geflogen, landet also gerade
                      s = [0 0];%[s_vec(end,1)-(s_vec(end,2)/v(2)*v(1)) 0];
                    end
                    v = [0 0];
                  end
                
                  % - Vorbereitung des nächsten Schrittes -
                  % Zeit weiterzählen
                  t = t + delta_t;
                
                  % Zeit und Bewegungsgrößen abspeichern
                  a_vec = [a_vec; a];
                  v_vec = [v_vec; v];
                  s_vec = [s_vec; s];
                  t_vec = [t_vec; t];
                end

                plot(ax_h,t_vec(:),s_vec(1:end,2))
                plot(ax_v,t_vec(:),sqrt(v_vec(1:end,1).^2 + v_vec(1:end,2).^2))
                h_max = max(s_vec(:,2));  % Maximalhöhe in m
                x_max = max(s_vec(:,1));  % Maximalhöhe in m
                v_max = max(sqrt(v_vec(1:end,1).^2 + v_vec(1:end,2).^2));  % Maximalhöhe in m
            end
            
            
            %% Flughöhen bestimmen
            hold(ax_h, "on");
            hold(ax_v, "on");
            % Alle Nutzlasten
            [h, x, v] = plot_with_m(app.masse1*1.1);
            maxes = [h, x, v];
            % Nur Kamera (mit Halterung)
            [h, x, v] = plot_with_m(app.masse2*1.1);
            maxes = max(maxes, [h, x, v]);
            % Nur Höhenlogger (in Nase --> keine Halterung, aber Trimmmmasse!!!)
            [h, x, v] = plot_with_m(app.masse3*1.1);
            maxes = max(maxes, [h, x, v]); % 20 + 4 - 10 gramm (Trimmmasse!!!)
            h=maxes(1); x=maxes(2); v=maxes(3);
            legend(ax_h, "beides", "nur Kamera", "nur Höhenlogger")
            hold(ax_h, "off");
            legend(ax_v, "beides", "nur Kamera", "nur Höhenlogger")
            hold(ax_v, "off");
            app.FlughoeheLabel.Text = app.prep_latex_str("h_\mathrm{max}", h, "m");
            app.AbweichungLabel.Text = app.prep_latex_str("x_\mathrm{max}", x, "m");
            app.GeschwindigkeitLabel.Text = app.prep_latex_str("v_\mathrm{max}", v, "m");
        end


        function r = drawContour(app)
            function meters = cm(value)
                meters = value/1e2;
            end

            ax = app.contourAxes;
            % clear the canvas
            cla(ax);
            % übernandermalen erlauben!
            hold(ax, "on");

            r_rumpf = app.d_rumpf/2;
            spann = app.s();
            A_Flosse = app.A_Flosse();
            l_vor = spann * tan(app.alpha_vor);
            l_hinter = spann * tan(app.alpha_hinter);
            l_mitte = A_Flosse/spann - (l_vor + l_hinter)/2;

            l_i = l_vor + l_mitte;
            l_a = l_hinter + l_mitte;
            % Werte anzeigen:
            app.l_iLabel.Text = app.prep_latex_str("l_\text{i}", l_i * 1e2, "cm");
            app.l_aLabel.Text = app.prep_latex_str("l_\text{a}", l_a * 1e2, "cm");
            
            
            % Achsenbeschriftungen
            format_fun = @(s) strip(strip(compose("%9.2f", s), "right", "0"), "right", ".");
            ax.XTick = [-spann-r_rumpf -r_rumpf 0 r_rumpf spann+r_rumpf];
            ax.XTickLabel = arrayfun(format_fun, ax.XTick*100);
            ax.YTick = [app.l_rumpf app.l_rumpf-l_i app.l_nase 0] * (-1);
            ax.YTickLabel = arrayfun(format_fun, ax.YTick*(-100));


            % erstmal den Rumpf malen!
            r = rectangle(ax, 'Position', [-r_rumpf, -app.l_rumpf, app.d_rumpf, app.l_rohr]);
            x_max = r_rumpf + spann;
            y_min = -app.l_rumpf + min(0,-l_hinter);
            axis(ax, [-x_max-cm(10), x_max+cm(10), y_min-cm(1), cm(1)]);


            % jetzt die Nase
            r_rumpf_sq = r_rumpf^2;
            switch app.NasenformDropDown.Value
                case "Parabel"
                    nose_f = @(x) (app.l_nase * (1 - x.^2/r_rumpf_sq));
                case "Ellipse"
                    nose_f = @(x) (app.l_nase * sqrt(1 - x.^2/r_rumpf_sq));
                otherwise % Konisch
                    nose_f = @(x) (app.l_nase * (1 - abs(x)/r_rumpf));
            end

            nose_x = linspace(-r_rumpf, r_rumpf);
            fill(ax, nose_x, nose_f(nose_x) - app.l_nase, 'r');

            % Flossen malen
            flosse_X = [0, spann, spann, 0] + r_rumpf;
            flosse_Y = [l_i, l_i-l_vor, -l_hinter, 0] - app.l_rumpf;
            switch app.n_flossen
                case 3
                    fill(ax, [flosse_X; -flosse_X/2]', repmat(flosse_Y, 2, 1)', "r");
                case {2, 4}
                    fill(ax, [-flosse_X; flosse_X]', repmat(flosse_Y, 2, 1)', "r");
                case 6
                    fill(ax, [-flosse_X; flosse_X; -flosse_X/2; flosse_X/2]', repmat(flosse_Y, 4, 1)', "r");
                otherwise
                    fill(ax, flosse_X, flosse_Y, "r");
            end


            %% Massenschwerpunkte

            % Flossen
            A_vor = l_vor*spann/2;
            A_hinter = l_hinter*spann/2;
            A_mitte = l_mitte*spann;
            c_g_flosse = (A_vor * [2/3*spann 1/3*l_vor+l_hinter+l_mitte] + A_mitte * [spann/2 l_hinter+l_mitte/2] + A_hinter * [2/3*spann (2/3*l_hinter)]) / A_Flosse + [r_rumpf -app.l_rumpf - l_hinter];

            % plot(ax, c_g_flosse(1), c_g_flosse(2),'.','MarkerSize',14,'MarkerEdgeColor','yellow');
            % text(ax, c_g_flosse(1), c_g_flosse(2),'\leftarrow Massenschwerpunkt Flosse')

            %app.x_CGFlosseLabel.Text = app.prep_latex_str("x_{\text{CG}_\text{Flosse}}", (c_g_flosse(2)) * (-1e3),"mm",2);

            %
            % Dichten
            %
            p_Styrodur = 33500; % g/m3
            p_3mmPappel = 1096.4; % g/m2

            x_CG_Flosse = -c_g_flosse(2);
            m_Flosse = A_Flosse * 197 * 9; % 3 lagen und 3 flossen;
            x_CG_Rohr = app.l_nase + app.l_rohr/2;
            m_Rohr = 14/0.3*app.l_rohr;
            x_CG_Nase = app.l_nase * 5/8;
            x_CG_Nase_Zylinder = app.l_nase + cm(0.5);
            x_CG_Nase_Cutout = app.l_nase - cm(3);
            m_Nase = (pi * r_rumpf.^2 * app.l_nase)/2 * p_Styrodur;
            m_Nase_Zylinder = pi * cm(1.7).^2 * cm(1) * p_Styrodur;
            m_Nase_Cutout =  cm(-1.6)*cm(1.05)*cm(8) * p_Styrodur;
            m_Motor = 16.8; % gramm
            x_CG_Motor = app.l_rumpf - cm(7)/3;
            x_CG_Kamera = app.l_nase - cm(0.8); 
            m_Kamera = 20; % gramm
            m_Nutzlasthalterung = 4; % gramm
            x_CG_Nutzlasthalterung = x_CG_Kamera;
            x_CG_Logger = x_CG_Nase_Cutout;
            m_Logger = 7.5; % gramm
            m_Leitrohr = 0.5; % gramm
            x_CG_Leitrohr = app.l_rumpf - cm(2);
            m_Watte = 4; % gramm
            x_CG_Watte = app.l_rumpf - cm(7.5);
            m_Bergung = 17.3; % gramm
            % switch app.konfiguration   % Nicht relevant, wenn der Fallschirm immer in der Röhre ist.                   
            %     case 4
            %         x_CG_Bergung = app.l_nase - cm(1);
            %     otherwise
            %         x_CG_Bergung = app.l_nase + cm(3);
            % end
            x_CG_Bergung = app.l_nase + cm(4);
            m_Motorhalt = pi * (cm(3.4/2).^2-cm(1.8/2).^2)* p_3mmPappel * 2;
            x_CG_Motorhalt = x_CG_Motor - cm(0.75);
            
            m_Trimm = 10; % gramm
            x_CG_Trimm = cm(2); % sehr weit vorn in der Nase

            
            %% Schwerpunkt bestimmen
            massen = [m_Nase m_Nase_Zylinder m_Nase_Cutout m_Rohr m_Nutzlasthalterung m_Flosse m_Motorhalt m_Kamera m_Motor m_Bergung m_Watte m_Leitrohr m_Logger m_Trimm];
            schwerpunkte = [x_CG_Nase x_CG_Nase_Zylinder x_CG_Nase_Cutout x_CG_Rohr x_CG_Nutzlasthalterung x_CG_Flosse x_CG_Motorhalt x_CG_Kamera x_CG_Motor x_CG_Bergung x_CG_Watte x_CG_Leitrohr x_CG_Logger x_CG_Trimm];
            
            masse1 = sum(massen) - m_Trimm;
            masse2 = sum(massen) - m_Trimm - m_Logger;
            masse3 = sum(massen) - m_Nutzlasthalterung - m_Kamera;
            app.masse1 = masse1/1000  % Umrechnung in kg
            app.masse2 = masse2/1000;
            app.masse3 = masse3/1000;

            switch app.konfiguration
                case 1
                    masse = masse1;
                    x_CG = (sum(massen.*schwerpunkte)-m_Trimm*x_CG_Trimm) / masse;
                case 2
                    masse = masse2;
                    x_CG = (sum(massen.*schwerpunkte)-m_Logger*x_CG_Logger-m_Trimm*x_CG_Trimm) / masse;
                case 3
                    masse = masse3;
                    x_CG = (sum(massen.*schwerpunkte)-m_Nutzlasthalterung*x_CG_Nutzlasthalterung-m_Kamera*x_CG_Kamera) / masse;
                otherwise
            end
            % Anzeigen
            plot(ax, 0, -x_CG,'.','MarkerSize',14,'MarkerEdgeColor','yellow');
            disp("massen: " + num2str(massen));
            disp("schwerpunkte: " + num2str(schwerpunkte*1000));
            app.x_CGLabel.Text = app.prep_latex_str("\text{Schwerpunkt }x_{\text{CG}}", x_CG*1000,"mm",2);
            text(ax, 0.001, -x_CG,'\leftarrow Schwerpunkt x_{CG}')
            app.MasseLabel.Text = app.prep_latex_str("\text{Gesamtmasse+10\% }m", masse*1.1, "g", 2);
            app.d_FallschirmLabel.Text = app.prep_latex_str("d_\text{min,Fallschirm}", app.d_fallschirmMin*100, "cm", 1);
            
            % Färbe Eingabefeld ein,je nachdem, ob Wert hoch genug ist
            if app.d_fallschirm >= app.d_fallschirmMin
                app.d_FallschirmEditField.BackgroundColor = [0.5, 1, 0.5];  % grün
            else
                app.d_FallschirmEditField.BackgroundColor = [1, 0.5, 0.5];  % rot
            end


            % Spannweite bemaßen
            quiver(ax, r_rumpf, l_i + cm(1) - app.l_rumpf, spann, 0, 0, "filled", 'Color', 'k', 'LineWidth', 2, 'MaxHeadSize', .3);
            quiver(ax, r_rumpf + spann, l_i + cm(1) - app.l_rumpf, -spann, 0, 0, "filled", 'Color', 'k', 'LineWidth', 2,'MaxHeadSize', .3);
            line(ax, ones(2)*spann + r_rumpf, [cm(1.3), -l_vor] + l_i - app.l_rumpf, 'Color', 'k');
            text(ax, r_rumpf + cm(.5), l_i + cm(1.8) - app.l_rumpf, "Spannweite s");

            % Beschriftung l_a
            line(ax, [0, cm(2.3)] + spann + r_rumpf, zeros(2) + (-l_hinter-app.l_rumpf), 'Color', 'k')
            line(ax, [0, cm(2.3)] + spann + r_rumpf, zeros(2) + (l_i-l_vor-app.l_rumpf), 'Color', 'k');
            % line(ax, [0, cm(2.3)] + spann + r_rumpf, zeros(2) - l_hinter - app.l_rumpf, 'Color', 'k');
            quiver(ax, spann + r_rumpf + cm(2), l_i-l_vor-app.l_rumpf, 0, -l_a, 0, "filled", 'Color', 'k', 'LineWidth', 2, 'MaxHeadSize', .3);
            quiver(ax, spann + r_rumpf + cm(2), -l_hinter-app.l_rumpf, 0, l_a, 0, "filled", 'Color', 'k', 'LineWidth', 2, 'MaxHeadSize', .3);
            t = text(ax, r_rumpf + spann + cm(1.2), -l_hinter + l_a/2 - app.l_rumpf, "Flossenspitze l_a", "HorizontalAlignment","center");
            t.Rotation = 90;

            % Beschriftung l_i
            line(ax, [0, cm(4.3) + spann] + r_rumpf, zeros(2) + l_i - app.l_rumpf, 'Color', 'k');
            line(ax, [0, cm(4.3)] + spann + r_rumpf, zeros(2) - app.l_rumpf, 'Color', 'k');
            quiver(ax, spann + r_rumpf + cm(4), l_i - app.l_rumpf, 0, -l_i, 0, "filled", 'Color', 'k', 'LineWidth', 2, 'MaxHeadSize', .3);
            quiver(ax, spann + r_rumpf + cm(4), -app.l_rumpf, 0, l_i, 0, "filled", 'Color', 'k', 'LineWidth', 2, 'MaxHeadSize', .3);
            t = text(ax, spann + r_rumpf + cm(3.2), l_i/2 - app.l_rumpf, "Wurzel l_i", "HorizontalAlignment","center");
            t.Rotation = 90;


            %% Druckpunkte

            % Rumpf
            x_CP_Rumpf = app.l_rohr/2;
            C_N_al_Rumpf = 0;

            % Nase
            switch app.NasenformDropDown.Value
                case "Parabel"
                    x_CP_Nase = 1/2 * app.l_nase;
                case "Ellipse"
                    x_CP_Nase = -1/2 * app.l_nase;
                otherwise % Konisch
                    x_CP_Nase = 2/3 * app.l_nase;
            end
            C_N_al_Nase = 2*app.A_Nose()/app.A_ref;

            % Flosse
            x_CP_Flosse = (l_vor*(l_i+2*l_a))/(3*(l_i+l_a)) + (1/6) * (l_i+l_a- (l_i*l_a)/(l_i+l_a));
            % ist der Abstand zur Wurzel oder zur Spitze (sof. diese rückläufig ist == neg Winkel) gemeint?
            x_CP_LTW = (app.l_rumpf - l_i - min(0, l_vor)) + x_CP_Flosse;

            C_N_al_Flosse = 2 * pi * app.streckung/(2+sqrt(4+(app.streckung/cos(app.phi50()))^2)) * A_Flosse/app.A_ref;
            switch app.n_flossen
                case {3, 4}
                    K_fb = 1 + (r_rumpf/spann)/(1 + (r_rumpf/spann));
                case 6
                    K_fb = 1 + ((0.5*r_rumpf)/spann)/(1 + (r_rumpf/spann));
                otherwise
                    K_fb = 1;
            end

            C_N_al_fb = K_fb * C_N_al_Flosse;

            switch app.n_flossen
                case 3
                    C_N_al_LTW = 2*cos(deg2rad(30))*C_N_al_fb;
                case 4
                    C_N_al_LTW = 2*C_N_al_fb;
                otherwise
                    C_N_al_LTW = C_N_al_fb; % falsch, sollte der Projektionsanteil aus A_LTW sein!
            end
            

            disp("C_N_al_Flosse: " + C_N_al_Flosse)
            disp("C_N_al_fb: " + C_N_al_fb)
            disp("C_N_al_LTW: " + C_N_al_LTW)
            disp("C_N_al_Nase: " + C_N_al_Nase)
            disp("x_CP_LTW: " + x_CP_LTW*10^2)
            disp("x_CP_Nase: " + x_CP_Nase*10^2)

            %plot(ax, 0, -x_CP_Nase,'.','MarkerSize',14,'MarkerEdgeColor','k'); % Druckpunkt
            %plot(ax, r_rumpf + spann/2, -x_CP_LTW,'.','MarkerSize',14,'MarkerEdgeColor','k'); % Druckpunkt

            C_N_al = C_N_al_Nase + C_N_al_LTW + C_N_al_Rumpf;
            x_CP = (C_N_al_LTW * x_CP_LTW + C_N_al_Nase * x_CP_Nase)/C_N_al;
            % x_CP anzeigen
            app.DruckpunktLabel.Text = app.prep_latex_str("\text{Druckpunkt }x_{CP}", x_CP * 10^3, "mm", 2);

            plot(ax, 0, -x_CP,'.','MarkerSize',10,'MarkerEdgeColor','k','MarkerFaceColor','k'); % Druckpunkt
            text(ax, -0.001, -x_CP,'Druckpunkt x_{CP} \rightarrow', 'HorizontalAlignment','right');


            % Stabilität 
            app.StabilitaetLabel.Text = app.prep_latex_str("\text{Stabilität}", (x_CP-x_CG)/app.d_rumpf, "", 2);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function showApp(app)
            app.recomputeValues();
            daspect(app.contourAxes, [1 1 1]);
        end

        % Value changed function: FlossenzahlSpinner, NasenformDropDown
        function recomputeValues(app, event)
            app.gesamtwiderstand();
            app.drawContour();
            app.flugbahn();
        end

        % Value changed function: NasenlaengeSlider
        function NasenlaengeSliderValueChanged(app, event)
            value = app.NasenlaengeSlider.Value;
            app.NasenlaengeEditField.Value = value;
            app.recomputeValues();
        end

        % Value changed function: NasenlaengeEditField
        function NasenlaengeEditFieldValueChanged(app, event)
            value = app.NasenlaengeEditField.Value;
            app.NasenlaengeSlider.Value = value;
            app.recomputeValues();
        end

        % Value changed function: RumpflaengeEditField
        function RumpflaengeEditFieldValueChanged(app, event)
            value = app.RumpflaengeEditField.Value;
            app.RumpflaengeSlider.Value = value;
            app.recomputeValues();
        end

        % Value changed function: RumpflaengeSlider
        function RumpflaengeSliderValueChanged(app, event)
            value = round(app.RumpflaengeSlider.Value*2)/2;
            app.RumpflaengeSlider.Value = value;
            app.RumpflaengeEditField.Value = value;
            app.recomputeValues();
        end

        % Value changed function: FlaechenverhaeltnisEditField
        function FlaechenverhaeltnisEditFieldValueChanged(app, event)
            value = app.FlaechenverhaeltnisEditField.Value;
            app.FlaechenverhaeltnisSlider.Value = value;
            app.recomputeValues();
        end

        % Value changed function: FlaechenverhaeltnisSlider
        function FlaechenverhaeltnisSliderValueChanged(app, event)
            value = app.FlaechenverhaeltnisSlider.Value;
            app.FlaechenverhaeltnisEditField.Value = value;
            app.recomputeValues();
        end

        % Value changed function: VorderkantenpfeilungEditField
        function VorderkantenpfeilungEditFieldValueChanged(app, event)
            value = app.VorderkantenpfeilungEditField.Value;
            app.VorderkantenpfeilungSlider.Value = value;
            app.recomputeValues();
        end

        % Value changed function: VorderkantenpfeilungSlider
        function VorderkantenpfeilungSliderValueChanged(app, event)
            value = round(app.VorderkantenpfeilungSlider.Value);
            if (abs(value) == 90)
                value = value/90 * 89;
            end
            app.VorderkantenpfeilungSlider.Value = value;
            app.VorderkantenpfeilungEditField.Value = value;
            app.recomputeValues();
        end

        % Value changed function: HinterkantenpfeilungEditField
        function HinterkantenpfeilungEditFieldValueChanged(app, event)
            value = app.HinterkantenpfeilungEditField.Value;
            app.HinterkantenpfeilungSlider.Value = value;
            app.recomputeValues();
        end

        % Value changed function: HinterkantenpfeilungSlider
        function HinterkantenpfeilungSliderValueChanged(app, event)
            value = round(app.HinterkantenpfeilungSlider.Value);
            if (abs(value) == 90)
                value = value/90 * 89;
            end
            app.HinterkantenpfeilungSlider.Value = value;
            app.HinterkantenpfeilungEditField.Value = value;
            app.recomputeValues();
        end

        % Value changed function: StreckungEditField
        function StreckungEditFieldValueChanged(app, event)
            value = app.StreckungEditField.Value;
            app.StreckungSlider.Value = value;
            
        end

        % Value changed function: StreckungSlider
        function StreckungSliderValueChanged(app, event)
            value = app.StreckungSlider.Value;
            app.StreckungEditField.Value = value;
            app.recomputeValues();
        end

        % Value changed function: RumpfdurchmesserDropDown
        function RumpfdurchmesserDropDownValueChanged(app, event)
            value = app.RumpfdurchmesserDropDown.Value/10; % mm -> cm
            m = [6*value 25*value];
            app.RumpflaengeEditField.Limits = m;
            app.RumpflaengeSlider.Limits = m;
            app.recomputeValues();
        end

        % Value changed function: d_FallschirmEditField
        function d_FallschirmEditFieldValueChanged(app, event)
            value = app.d_FallschirmEditField.Value;
            app.recomputeValues();
        end

        % Value changed function: KonfigurationDropDown
        function KonfigurationDropDownValueChanged(app, event)
            value = app.KonfigurationDropDown.Value;
            app.recomputeValues();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FlugbahnAxes = uiaxes(app.GridLayout);
            title(app.FlugbahnAxes, 'Flugbahn')
            xlabel(app.FlugbahnAxes, 'Zeit t in s')
            ylabel(app.FlugbahnAxes, 'Flughöhe h in m')
            zlabel(app.FlugbahnAxes, 'Z')
            app.contourAxes = uiaxes(app.GridLayout);
            title(app.contourAxes, 'Konturplot')
            xlabel(app.contourAxes, 'Breite in cm')
            ylabel(app.contourAxes, 'Länge in cm')
            zlabel(app.contourAxes, 'Z')
            app.contourAxes.XTick = 0;
            app.contourAxes.YTick = 0;
            app.FluggeschwindigkeitAxes = uiaxes(app.GridLayout);
            title(app.FluggeschwindigkeitAxes, 'Fluggeschwindigkeit')
            xlabel(app.FluggeschwindigkeitAxes, 'Zeit t in s')
            ylabel(app.FluggeschwindigkeitAxes, 'Geschwindigkeit v in m/s')
            zlabel(app.FluggeschwindigkeitAxes, 'Z')
            app.NasenlngecmLabel = uilabel(app.GridLayout);
            app.NasenlngecmLabel.Text = 'Nasenlänge [cm]';
            app.NasenlaengeEditField = uieditfield(app.GridLayout, 'numeric');
            app.NasenlaengeEditField.LowerLimitInclusive = 'off';
            app.NasenlaengeEditField.UpperLimitInclusive = 'off';
            app.NasenlaengeEditField.Limits = [0 100];
            app.NasenlaengeEditField.ValueChangedFcn = createCallbackFcn(app, @NasenlaengeEditFieldValueChanged, true);
            app.NasenlaengeEditField.Value = 9;
            app.RumpflngecmLabel = uilabel(app.GridLayout);
            app.RumpflngecmLabel.Text = 'Rumpflänge [cm]';
            app.RumpflaengeEditField = uieditfield(app.GridLayout, 'numeric');
            app.RumpflaengeEditField.Limits = [21 100];
            app.RumpflaengeEditField.ValueChangedFcn = createCallbackFcn(app, @RumpflaengeEditFieldValueChanged, true);
            app.RumpflaengeEditField.Value = 50;
            app.NasenformDropDownLabel = uilabel(app.GridLayout);
            app.NasenformDropDownLabel.Text = 'Nasenform';
            app.NasenformDropDown = uidropdown(app.GridLayout);
            app.NasenformDropDown.Items = {'Parabel', 'Ellipse'};
            app.NasenformDropDown.ValueChangedFcn = createCallbackFcn(app, @recomputeValues, true);
            app.NasenformDropDown.Value = 'Parabel';
            app.FlchenverhltnisEditFieldLabel = uilabel(app.GridLayout);
            app.FlchenverhltnisEditFieldLabel.Text = 'Flächenverhältnis';
            app.FlaechenverhaeltnisEditField = uieditfield(app.GridLayout, 'numeric');
            app.FlaechenverhaeltnisEditField.Limits = [0 2];
            app.FlaechenverhaeltnisEditField.ValueChangedFcn = createCallbackFcn(app, @FlaechenverhaeltnisEditFieldValueChanged, true);
            app.FlaechenverhaeltnisEditField.Value = 0.3;
            app.RumpfdurchmessermmEditFieldLabel = uilabel(app.GridLayout);
            app.RumpfdurchmessermmEditFieldLabel.Text = 'Rumpfdurchmesser [mm]';
            app.VorderkantenpfeilungLabel = uilabel(app.GridLayout);
            app.VorderkantenpfeilungLabel.Text = 'Vorderkantenpfeilung [°]';
            app.VorderkantenpfeilungEditField = uieditfield(app.GridLayout, 'numeric');
            app.VorderkantenpfeilungEditField.LowerLimitInclusive = 'off';
            app.VorderkantenpfeilungEditField.UpperLimitInclusive = 'off';
            app.VorderkantenpfeilungEditField.Limits = [-90 90];
            app.VorderkantenpfeilungEditField.ValueChangedFcn = createCallbackFcn(app, @VorderkantenpfeilungEditFieldValueChanged, true);
            app.VorderkantenpfeilungEditField.Value = 65;
            app.HinterkantenpfeilungLabel = uilabel(app.GridLayout);
            app.HinterkantenpfeilungLabel.Text = 'Hinterkantenpfeilung [°]';
            app.HinterkantenpfeilungEditField = uieditfield(app.GridLayout, 'numeric');
            app.HinterkantenpfeilungEditField.LowerLimitInclusive = 'off';
            app.HinterkantenpfeilungEditField.UpperLimitInclusive = 'off';
            app.HinterkantenpfeilungEditField.Limits = [-90 90];
            app.HinterkantenpfeilungEditField.ValueChangedFcn = createCallbackFcn(app, @HinterkantenpfeilungEditFieldValueChanged, true);
            app.HinterkantenpfeilungEditField.Value = 30;
            app.StreckungEditFieldLabel = uilabel(app.GridLayout);
            app.StreckungEditFieldLabel.Text = 'Streckung';
            app.StreckungEditField = uieditfield(app.GridLayout, 'numeric');
            app.StreckungEditField.LowerLimitInclusive = 'off';
            app.StreckungEditField.Limits = [0 10];
            app.StreckungEditField.ValueChangedFcn = createCallbackFcn(app, @StreckungEditFieldValueChanged, true);
            app.StreckungEditField.Value = 2.5;
            app.phi50Label = uilabel(app.GridLayout);
            app.phi50Label.Interpreter = 'latex';
            app.phi50Label.Text = '$\varphi_{50}=-1\degree$';
            app.c_w_gesLabel = uilabel(app.GridLayout);
            app.c_w_gesLabel.Interpreter = 'latex';
            app.c_w_gesLabel.Text = '$c_{W_\mathrm{ges}}=-1$';
            app.wLabel = uilabel(app.GridLayout);
            app.wLabel.Interpreter = 'latex';
            app.wLabel.Text = 'Gesamtwiderstand $w$';
            app.A_FlosseLabel = uilabel(app.GridLayout);
            app.A_FlosseLabel.Interpreter = 'latex';
            app.A_FlosseLabel.Text = '$A_\text{Flosse} = -1$';
            app.A_LatLabel = uilabel(app.GridLayout);
            app.A_LatLabel.Interpreter = 'latex';
            app.A_LatLabel.Text = '$A_\text{Lat} = -1$';
            app.A_MantelLabel = uilabel(app.GridLayout);
            app.A_MantelLabel.Interpreter = 'latex';
            app.A_MantelLabel.Text = '$A_{\text{Mantel}} = -1$';
            app.SpannweiteLabel = uilabel(app.GridLayout);
            app.SpannweiteLabel.Interpreter = 'latex';
            app.SpannweiteLabel.Text = 'Spannweite $s = -1$';
            app.l_iLabel = uilabel(app.GridLayout);
            app.l_iLabel.Interpreter = 'latex';
            app.l_iLabel.Text = '$l_\mathrm{i}=-1$';
            app.l_aLabel = uilabel(app.GridLayout);
            app.l_aLabel.Interpreter = 'latex';
            app.l_aLabel.Text = '$l_\mathrm{a}=-1$';
            app.RumpfdurchmesserDropDown = uidropdown(app.GridLayout);
            app.RumpfdurchmesserDropDown.Items = {'35 mm', '40 mm'};
            app.RumpfdurchmesserDropDown.ItemsData = [35 40];
            app.RumpfdurchmesserDropDown.ValueChangedFcn = createCallbackFcn(app, @RumpfdurchmesserDropDownValueChanged, true);
            app.RumpfdurchmesserDropDown.Value = 35;
            app.FlossenzahlSpinnerLabel = uilabel(app.GridLayout);
            app.FlossenzahlSpinnerLabel.Text = 'Flossenzahl';
            app.FlossenzahlSpinner = uispinner(app.GridLayout);
            app.FlossenzahlSpinner.Limits = [2 6];
            app.FlossenzahlSpinner.ValueChangedFcn = createCallbackFcn(app, @recomputeValues, true);
            app.FlossenzahlSpinner.Value = 3;
            app.StreckungSlider = uislider(app.GridLayout);
            app.StreckungSlider.Limits = [0 10];
            app.StreckungSlider.ValueChangedFcn = createCallbackFcn(app, @StreckungSliderValueChanged, true);
            app.StreckungSlider.Value = 2.5;
            app.NasenlaengeSlider = uislider(app.GridLayout);
            app.NasenlaengeSlider.MajorTicks = [0 25 50 75 100];
            app.NasenlaengeSlider.ValueChangedFcn = createCallbackFcn(app, @NasenlaengeSliderValueChanged, true);
            app.NasenlaengeSlider.Value = 9;
            app.RumpflaengeSlider = uislider(app.GridLayout);
            app.RumpflaengeSlider.Limits = [21 100];
            app.RumpflaengeSlider.MajorTicks = [21 30 40 50 60 70 80 90 100];
            app.RumpflaengeSlider.ValueChangedFcn = createCallbackFcn(app, @RumpflaengeSliderValueChanged, true);
            app.RumpflaengeSlider.Value = 50;
            app.FlaechenverhaeltnisSlider = uislider(app.GridLayout);
            app.FlaechenverhaeltnisSlider.Limits = [0 2];
            app.FlaechenverhaeltnisSlider.ValueChangedFcn = createCallbackFcn(app, @FlaechenverhaeltnisSliderValueChanged, true);
            app.FlaechenverhaeltnisSlider.Value = 0.3;
            app.VorderkantenpfeilungSlider = uislider(app.GridLayout);
            app.VorderkantenpfeilungSlider.Limits = [-90 90];
            app.VorderkantenpfeilungSlider.ValueChangedFcn = createCallbackFcn(app, @VorderkantenpfeilungSliderValueChanged, true);
            app.VorderkantenpfeilungSlider.Value = 65;
            app.HinterkantenpfeilungSlider = uislider(app.GridLayout);
            app.HinterkantenpfeilungSlider.Limits = [-90 90];
            app.HinterkantenpfeilungSlider.ValueChangedFcn = createCallbackFcn(app, @HinterkantenpfeilungSliderValueChanged, true);
            app.HinterkantenpfeilungSlider.Value = 30;
            app.DruckpunktLabel = uilabel(app.GridLayout);
            app.DruckpunktLabel.Interpreter = 'latex';
            app.DruckpunktLabel.Text = 'Druckpunkt $x_{CP}$';
            app.x_CGLabel = uilabel(app.GridLayout);
            app.x_CGLabel.Interpreter = 'latex';
            app.x_CGLabel.Text = '$x_{\text{CG}}$ = 1';
            app.StabilitaetLabel = uilabel(app.GridLayout);
            app.StabilitaetLabel.Interpreter = 'latex';
            app.StabilitaetLabel.Text = 'Stabilität = -1';
            app.MasseLabel = uilabel(app.GridLayout);
            app.MasseLabel.Interpreter = 'latex';
            app.MasseLabel.Text = 'Gesamtmasse m = -1';
            app.FlughoeheLabel = uilabel(app.GridLayout);
            app.FlughoeheLabel.Interpreter = 'latex';
            app.FlughoeheLabel.Text = '$h_\mathrm{max}$ = -1';
            app.d_FallschirmLabel = uilabel(app.GridLayout);
            app.d_FallschirmLabel.Interpreter = 'latex';
            app.d_FallschirmLabel.Text = '$d_\mathrm{min,Fallschirm}$ = -1 cm';
            app.FallschirmdurchmcmLabel = uilabel(app.GridLayout);
            app.FallschirmdurchmcmLabel.Text = 'Fallschirmdurchm. [cm]';
            app.d_FallschirmEditField = uieditfield(app.GridLayout, 'numeric');
            app.d_FallschirmEditField.Limits = [0 Inf];
            app.d_FallschirmEditField.ValueDisplayFormat = '%11g';
            app.d_FallschirmEditField.ValueChangedFcn = createCallbackFcn(app, @d_FallschirmEditFieldValueChanged, true);
            app.d_FallschirmEditField.Value = 42;
            app.GeschwindigkeitLabel = uilabel(app.GridLayout);
            app.GeschwindigkeitLabel.Interpreter = 'latex';
            app.GeschwindigkeitLabel.Text = '$v_\mathrm{max}$ = -1';
            app.AbweichungLabel = uilabel(app.GridLayout);
            app.AbweichungLabel.Interpreter = 'latex';
            app.AbweichungLabel.Text = '$s_\mathrm{max}$ = -1';
            app.KonfigurationDropDownLabel = uilabel(app.GridLayout);
            app.KonfigurationDropDownLabel.Text = 'Konfiguration';
            app.KonfigurationDropDown = uidropdown(app.GridLayout);
            app.KonfigurationDropDown.Items = {'Kamera und Höhenlogger', 'nur Kamera', 'nur Höhenlogger'};
            app.KonfigurationDropDown.ItemsData = [1 2 3];
            app.KonfigurationDropDown.ValueChangedFcn = createCallbackFcn(app, @KonfigurationDropDownValueChanged, true);
            app.KonfigurationDropDown.Value = 1;
            app.ExtremwertefralleKonfigurationenLabel = uilabel(app.GridLayout);
            app.ExtremwertefralleKonfigurationenLabel.Text = {'Extremwerte für '; 'alle Konfigurationen:'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @showApp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end